classdef UR3simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Q6EditField_2              matlab.ui.control.NumericEditField
        Q6EditField_2Label         matlab.ui.control.Label
        Q5EditField_2              matlab.ui.control.NumericEditField
        Q5EditField_2Label         matlab.ui.control.Label
        Q4EditField_2              matlab.ui.control.NumericEditField
        Q4EditField_2Label         matlab.ui.control.Label
        Q3EditField_2              matlab.ui.control.NumericEditField
        Q3EditField_2Label         matlab.ui.control.Label
        Q2EditField_2              matlab.ui.control.NumericEditField
        Q2EditField_2Label         matlab.ui.control.Label
        Q1EditField_2              matlab.ui.control.NumericEditField
        Q1EditField_2Label         matlab.ui.control.Label
        Q6EditField                matlab.ui.control.NumericEditField
        Q6EditFieldLabel           matlab.ui.control.Label
        Q5EditField                matlab.ui.control.NumericEditField
        Q5EditFieldLabel           matlab.ui.control.Label
        Q4EditField                matlab.ui.control.NumericEditField
        Q4EditFieldLabel           matlab.ui.control.Label
        Q3EditField                matlab.ui.control.NumericEditField
        Q3EditFieldLabel           matlab.ui.control.Label
        Q2EditField                matlab.ui.control.NumericEditField
        Q2EditFieldLabel           matlab.ui.control.Label
        Q1EditField                matlab.ui.control.NumericEditField
        Q1EditFieldLabel           matlab.ui.control.Label
        InicializarcinematicainversaButton  matlab.ui.control.Button
        UselosslidesLabel_2        matlab.ui.control.Label
        zEditField                 matlab.ui.control.NumericEditField
        zEditFieldLabel            matlab.ui.control.Label
        yEditField                 matlab.ui.control.NumericEditField
        yEditFieldLabel            matlab.ui.control.Label
        xEditField                 matlab.ui.control.NumericEditField
        xEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        PEditField                 matlab.ui.control.NumericEditField
        PEditFieldLabel            matlab.ui.control.Label
        REditField                 matlab.ui.control.NumericEditField
        REditFieldLabel            matlab.ui.control.Label
        QudeseasimularButtonGroup  matlab.ui.container.ButtonGroup
        CinematicainversaButton    matlab.ui.control.RadioButton
        CinematicadirectaButton    matlab.ui.control.RadioButton
        UselosslidesLabel          matlab.ui.control.Label
        InicializarcinematicadirectaButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        R;
        Q1=0;
        Q2=0;
        Q3=0;
        Q4=0;
        Q5=0;
        Q6=0;
        x=0;
        y=0;
        z=0;
        Ri=0;
        P=0;
        Y=0;
    end
    
    methods (Access = private)
        
        
        function R=UR3(a)
            L(1) = Link([0 0.152 0 0],'modified');
            L(2) = Link([0 0.120 0 pi/2 0 pi/2],'modified');
            L(3) = Link([0 0 0.244 0],'modified');
            L(4) = Link([0 -0.010 0.213 0 0 -pi/2],'modified');
            L(5) = Link([0 0.083 0 -pi/2],'modified');
            L(6) = Link([0 0.082 0 pi/2],'modified');
            R = SerialLink(L,'name','Universal Robot UR3');
        end
        
        function Rinv(app)
            Rot = rpy2tr(app.Ri,app.P,app.Y);
            trans = transl(app.x,app.y,app.z);
            T = trans*Rot;
            M = [1 1 1 1 1 0];
            qi = ikine(app.R,T,[0,0,0,0,0,0],M,'pinv','ilimit',2500);
            figure(1)
            app.R.plot(qi,'workspace',[-0.4 0.4 -0.4 0.4 0 0.8], ...
                'noa','view',[59.733 11.020])
            app.Q1EditField_2.Value = qi(1)*180/pi;
            app.Q2EditField_2.Value = qi(2)*180/pi;
            app.Q3EditField_2.Value = qi(3)*180/pi;
            app.Q4EditField_2.Value = qi(4)*180/pi;
            app.Q5EditField_2.Value = qi(5)*180/pi;
            app.Q6EditField_2.Value = qi(6)*180/pi;
        end
        function UR3plot(app)
            r=0.8;
            figure(1)
            app.R.plot([app.Q1 app.Q2 app.Q3 app.Q4 app.Q5 app.Q6], ...
                'workspace',[-r r -r r -r r], 'noa','view',[59.733 11.020])
            title("UR3")
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startup_rvc
            app.R = app.UR3();
        end

        % Button pushed function: InicializarcinematicadirectaButton
        function InicializarcinematicadirectaButtonPushed(app, event)
            app.UR3plot;
            app.Q1EditField.Enable = 'on';
            app.Q2EditField.Enable = 'on';
            app.Q3EditField.Enable = 'on';
            app.Q4EditField.Enable = 'on';
            app.Q5EditField.Enable = 'on';
            app.Q6EditField.Enable = 'on';
            
            app.Q1 =  app.Q1EditField.Value*pi/180;
            app.Q2 =  app.Q2EditField.Value*pi/180;
            app.Q3  = app.Q3EditField.Value*pi/180;
            app.Q4 =  app.Q4EditField.Value*pi/180;
            app.Q5 =  app.Q5EditField.Value*pi/180;
            app.Q6 =  app.Q6EditField.Value*pi/180;
            app.UR3plot;


        end

        % Callback function
        function Q1SliderValueChanging(app, event)
            app.Q1 = event.Value*pi/180;
            app.UR3plot;                
        end

        % Callback function
        function Q2SliderValueChanging(app, event)
            app.Q2 = event.Value*pi/180;
            app.UR3plot;
        end

        % Callback function
        function Q3SliderValueChanging(app, event)
            app.Q3 = event.Value*pi/180;
            app.UR3plot;            
        end

        % Callback function
        function Q4SliderValueChanging(app, event)
            app.Q4 = event.Value*pi/180;
            app.UR3plot;            
        end

        % Callback function
        function Q5SliderValueChanging(app, event)
            app.Q5 = event.Value*pi/180;
            app.UR3plot; 
        end

        % Callback function
        function Q6SliderValueChanging(app, event)
            app.Q6 = event.Value*pi/180;
            app.UR3plot; 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close all
            delete(app)
            
        end

        % Selection changed function: QudeseasimularButtonGroup
        function QudeseasimularButtonGroupSelectionChanged(app, event)
            selectedButton = app.QudeseasimularButtonGroup.SelectedObject;  
            close all;
            if(selectedButton== app.CinematicadirectaButton)
                app.InicializarcinematicadirectaButton.Enable = 'on';
                app.xEditField.Enable = 'off';
                app.yEditField.Enable = 'off';
                app.zEditField.Enable = 'off';
                app.REditField.Enable = 'off';
                app.PEditField.Enable = 'off';
                app.YEditField.Enable = 'off';
                app.xEditField.Value = 0;
                app.yEditField.Value = 0;
                app.zEditField.Value = 0;
                app.REditField.Value = 0;
                app.PEditField.Value = 0;
                app.YEditField.Value = 0;
                
            else
                app.InicializarcinematicadirectaButton.Enable = 'off';
                app.Q1EditField.Enable = 'off';
                app.Q2EditField.Enable = 'off';
                app.Q3EditField.Enable = 'off';
                app.Q4EditField.Enable = 'off';
                app.Q5EditField.Enable = 'off';
                app.Q6EditField.Enable = 'off';
                app.Q1EditField.Value = 0;
                app.Q2EditField.Value = 0;
                app.Q3EditField.Value = 0;
                app.Q4EditField.Value = 0;
                app.Q5EditField.Value = 0;
                app.Q6EditField.Value = 0;
                app.Q1 = 0;
                app.Q2 = 0;
                app.Q3 = 0;
                app.Q4 = 0;
                app.Q5 = 0;
                app.Q6 = 0;
                app.xEditField.Enable = 'on';
                app.yEditField.Enable = 'on';
                app.zEditField.Enable = 'on';
                app.REditField.Enable = 'on';
                app.PEditField.Enable = 'on';      
                app.YEditField.Enable = 'on';        
                app.InicializarcinematicainversaButton.Enable='on';
            end
        end

        % Button pushed function: InicializarcinematicainversaButton
        function InicializarcinematicainversaButtonPushed(app, event)
            app.x = app.xEditField.Value;
            app.y = app.yEditField.Value;
            app.z = app.zEditField.Value;
            app.Ri = app.REditField.Value;
            app.P = app.PEditField.Value;
            app.Y = app.YEditField.Value;
            app.Rinv();
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InicializarcinematicadirectaButton = uibutton(app.UIFigure, 'push');
            app.InicializarcinematicadirectaButton.ButtonPushedFcn = createCallbackFcn(app, @InicializarcinematicadirectaButtonPushed, true);
            app.InicializarcinematicadirectaButton.Text = 'Inicializar cinematica directa';
            app.UselosslidesLabel = uilabel(app.UIFigure);
            app.UselosslidesLabel.Text = 'Use los slides para indicar la orientación de cada articulación en grados';
            app.QudeseasimularButtonGroup = uibuttongroup(app.UIFigure);
            app.QudeseasimularButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @QudeseasimularButtonGroupSelectionChanged, true);
            app.QudeseasimularButtonGroup.Title = '¿Qué desea simular?';
            app.CinematicadirectaButton = uiradiobutton(app.QudeseasimularButtonGroup);
            app.CinematicadirectaButton.Text = 'Cinematica directa';
            app.CinematicadirectaButton.Value = true;
            app.CinematicainversaButton = uiradiobutton(app.QudeseasimularButtonGroup);
            app.CinematicainversaButton.Text = 'Cinematica inversa';
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.REditField.Enable = 'off';
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditField.Enable = 'off';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.Enable = 'off';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.Enable = 'off';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.Enable = 'off';
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditField.Enable = 'off';
            app.UselosslidesLabel_2 = uilabel(app.UIFigure);
            app.UselosslidesLabel_2.Text = 'Use los slides para indicar la orientación de cada articulación en grados';
            app.InicializarcinematicainversaButton = uibutton(app.UIFigure, 'push');
            app.InicializarcinematicainversaButton.ButtonPushedFcn = createCallbackFcn(app, @InicializarcinematicainversaButtonPushed, true);
            app.InicializarcinematicainversaButton.Enable = 'off';
            app.InicializarcinematicainversaButton.Text = 'Inicializar cinematica inversa';
            app.Q1EditFieldLabel = uilabel(app.UIFigure);
            app.Q1EditFieldLabel.Text = 'Q1';
            app.Q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q2EditFieldLabel = uilabel(app.UIFigure);
            app.Q2EditFieldLabel.Text = 'Q2';
            app.Q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q3EditFieldLabel = uilabel(app.UIFigure);
            app.Q3EditFieldLabel.Text = 'Q3';
            app.Q3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q4EditFieldLabel = uilabel(app.UIFigure);
            app.Q4EditFieldLabel.Text = 'Q4';
            app.Q4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q5EditFieldLabel = uilabel(app.UIFigure);
            app.Q5EditFieldLabel.Text = 'Q5';
            app.Q5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q6EditFieldLabel = uilabel(app.UIFigure);
            app.Q6EditFieldLabel.Text = 'Q6';
            app.Q6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q1EditField_2Label = uilabel(app.UIFigure);
            app.Q1EditField_2Label.Text = 'Q1';
            app.Q1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Q1EditField_2.Editable = 'off';
            app.Q2EditField_2Label = uilabel(app.UIFigure);
            app.Q2EditField_2Label.Text = 'Q2';
            app.Q2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Q2EditField_2.Editable = 'off';
            app.Q3EditField_2Label = uilabel(app.UIFigure);
            app.Q3EditField_2Label.Text = 'Q3';
            app.Q3EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Q3EditField_2.Editable = 'off';
            app.Q4EditField_2Label = uilabel(app.UIFigure);
            app.Q4EditField_2Label.Text = 'Q4';
            app.Q4EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Q4EditField_2.Editable = 'off';
            app.Q5EditField_2Label = uilabel(app.UIFigure);
            app.Q5EditField_2Label.Text = 'Q5';
            app.Q5EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Q5EditField_2.Editable = 'off';
            app.Q6EditField_2Label = uilabel(app.UIFigure);
            app.Q6EditField_2Label.Text = 'Q6';
            app.Q6EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Q6EditField_2.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UR3simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end